classdef Benutzereingabe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StoppenSim                      matlab.ui.control.Button
        StartenSim                      matlab.ui.control.Button
        SimulinkSim                     matlab.ui.control.Label
        SimulinkLabel                   matlab.ui.control.Label
        Simulinkstart                   matlab.ui.control.Button
        ResetLabel                      matlab.ui.control.Label
        ResetInit                       matlab.ui.control.Button
        PfadStatusLamp                  matlab.ui.control.Lamp
        PfadStatusLampLabel             matlab.ui.control.Label
        ylim                            matlab.ui.control.NumericEditField
        Obs3XEditField_2Label_2         matlab.ui.control.Label
        xlim                            matlab.ui.control.NumericEditField
        Obs2XEditField_2Label_2         matlab.ui.control.Label
        AchsgrenzenLabel                matlab.ui.control.Label
        StartenderPfadplanung           matlab.ui.control.Label
        StartPfadplanungButton          matlab.ui.control.Button
        Obs3Radius                      matlab.ui.control.NumericEditField
        Obs3RadiusEditFieldLabel        matlab.ui.control.Label
        Obs2Radius                      matlab.ui.control.NumericEditField
        Obs2RadiusEditFieldLabel        matlab.ui.control.Label
        Obs1Radius                      matlab.ui.control.NumericEditField
        Obs1RadiusEditFieldLabel        matlab.ui.control.Label
        Obs3Y                           matlab.ui.control.NumericEditField
        Obs3YEditFieldLabel             matlab.ui.control.Label
        Obs2Y                           matlab.ui.control.NumericEditField
        Obs2YEditFieldLabel             matlab.ui.control.Label
        Obs1Y                           matlab.ui.control.NumericEditField
        Obs1YEditFieldLabel             matlab.ui.control.Label
        Obs3X                           matlab.ui.control.NumericEditField
        Obs3XEditField_2Label           matlab.ui.control.Label
        Obs2X                           matlab.ui.control.NumericEditField
        Obs2XEditField_2Label           matlab.ui.control.Label
        Obs1X                           matlab.ui.control.NumericEditField
        Obs1XEditField_2Label           matlab.ui.control.Label
        EndPositionY                    matlab.ui.control.NumericEditField
        EndPositionYEditField_2Label    matlab.ui.control.Label
        StartPositionY                  matlab.ui.control.NumericEditField
        StartPositionYEditField_2Label  matlab.ui.control.Label
        EndPositionX                    matlab.ui.control.NumericEditField
        EndPositionXEditField_2Label    matlab.ui.control.Label
        StartPositionX                  matlab.ui.control.NumericEditField
        StartPositionXEditField_2Label  matlab.ui.control.Label
        EingabederHindernisse           matlab.ui.control.Label
        EingabeStartundZielpunkt        matlab.ui.control.Label
        Banner                          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Rover parameter (hartcodiert)
        l = 2;
        b = 1;
        h = 0.5;
        r = 0.2;
        w = 0.2;

        z = 1;      % Höhe im Raum

        % Initialisierung Anfangszustände
        dthetas = ones(4,1);

        phi = ones(4,1);

        q0 = zeros(3,1);
        
        % Map parameter
        cellSize = 1.0;
        safety;
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartPfadplanungButton
        function StartPfadplanungButtonPushed(app, event)
            
            % Lampe zurücksetzen
            app.PfadStatusLamp.Color = [0.5 0.5 0.5];  % Grau
            app.PfadStatusLamp.Tooltip = 'Bereit';

            % Werte holen
            start_pos = [app.StartPositionX.Value, app.StartPositionY.Value, 0];
            end_pos   = [app.EndPositionX.Value, app.EndPositionY.Value, 0];
            
            X_value_obs = [app.Obs1X.Value, app.Obs2X.Value, app.Obs3X.Value];
            Y_value_obs = [app.Obs1Y.Value, app.Obs2Y.Value, app.Obs3Y.Value];
            r_obs       = [app.Obs1Radius.Value, app.Obs2Radius.Value, app.Obs3Radius.Value];
            
            % Map erzeugen
            xlim_end = app.xlim.Value;
            ylim_end = app.ylim.Value;

            mapSize = [ylim_end, xlim_end] / app.cellSize;
            map = zeros(mapSize);
            
            % Sicherheitsabstand definieren
            app.safety = app.b / 2;

            % Hindernis eintragen
            map = fct_markCircularObstacle(map, X_value_obs(1), Y_value_obs(1), r_obs(1) + app.safety, app.cellSize);
            map = fct_markCircularObstacle(map, X_value_obs(2), Y_value_obs(2), r_obs(2) + app.safety, app.cellSize);
            map = fct_markCircularObstacle(map, X_value_obs(3), Y_value_obs(3), r_obs(3) + app.safety, app.cellSize);
            
            % Start- und Endzelle
            start_cell = round(start_pos(1:2) / app.cellSize);
            goal_cell  = round(end_pos(1:2) / app.cellSize);
            
            % Überprüfung ob Start/Ziel in Hindernissen
            d_start = sqrt((start_pos(1) - X_value_obs).^2 + (start_pos(2) - Y_value_obs).^2);

            d_end = sqrt((end_pos(1) - X_value_obs).^2 + (end_pos(2) - Y_value_obs).^2);

            if any(d_start <= (r_obs + app.safety)) || any(d_end <= (r_obs + app.safety))
                    errordlg('Start oder Ziel liegen im Hindernis!', 'Hinderniswarnung');
                    app.PfadStatusLamp.Color = 'red';  % Rot
                    app.PfadStatusLamp.Tooltip = 'Ungültige Start-/Zielposition';
                return;
            end
            
            % BFS starten
                result_path = bfs_pathfinding(map, start_cell, goal_cell);
            
            if isempty(result_path)
                    errordlg('Kein Pfad gefunden!', 'Fehler');
                    app.PfadStatusLamp.Color = 'red';
                    app.PfadStatusLamp.Tooltip = 'Kein Pfad gefunden';
            else
                    % msgbox('Pfad erfolgreich erstellt!', 'Erfolg');
                    app.PfadStatusLamp.Color = 'green';
                    app.PfadStatusLamp.Tooltip = 'Pfad erfolgreich gefunden';
                
                % Visualisierung der Ansicht in AppDesigner

                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                axis(app.UIAxes, [0 xlim_end 0 ylim_end]);
                axis(app.UIAxes, 'equal');
                app.UIAxes.Color = 'w'; % Hintergrund weiß
                grid(app.UIAxes, 'on');

                % Hindernisse visualisieren
                theta = linspace(0, 2*pi, 100); 

                for i = 1:length(X_value_obs)
                x = X_value_obs(i);
                y = Y_value_obs(i);
                r = r_obs(i);

                % Kreise zeichnen
                fill(app.UIAxes, x + r*cos(theta), y + r*sin(theta), [0.8 0.3 0.3], ...
                    'FaceAlpha', 0.3, 'EdgeColor', [0.8 0.3 0.3], 'LineWidth', 1.5);
                end

                % Pfad  
                plot(app.UIAxes, result_path(:,1), result_path(:,2), 'b.-', 'LineWidth', 2);

                hold(app.UIAxes, 'off');


                % Parameter an die MATLAB-Workspace senden
                
                % Arbeitsparameter
                assignin('base', 'start_pos', start_pos);
                assignin('base', 'end_pos', end_pos);
                assignin('base', 'xlim_end', xlim_end);
                assignin('base', 'ylim_end', ylim_end);
                assignin('base', 'X_value_obs', X_value_obs);
                assignin('base', 'Y_value_obs', Y_value_obs);
                assignin('base', 'r_obs', r_obs);
                assignin('base', 'map', map);
                assignin('base', 'result_path', result_path);

                % Rover-Parameter
                assignin('base', 'l', app.l);
                assignin('base', 'b', app.b);
                assignin('base', 'h', app.h);
                assignin('base', 'r', app.r);
                assignin('base', 'w', app.w);
                assignin('base', 'z', app.z);
                assignin('base', 'dthetas', app.dthetas);
                assignin('base', 'phi', app.phi);
                assignin('base', 'q0', app.q0);
                assignin('base', 'cellSize', app.cellSize);
                assignin('base', 'safety', app.safety);
                
            end
        end

        % Button pushed function: ResetInit
        function ResetInitButtonPushed(app, event)
            % Achsen leeren und zurücksetzen
            cla(app.UIAxes);
            app.UIAxes.Color = 'w';  % Hintergrund weiß
            axis(app.UIAxes, 'auto');
            grid(app.UIAxes, 'off');

            % Lampe zurücksetzen
            app.PfadStatusLamp.Color = [0.5 0.5 0.5];  % Grau
            app.PfadStatusLamp.Tooltip = 'Bereit';

            % Eingabefelder zurücksetzen (wenn gewünscht)
            app.StartPositionX.Value = 0;
            app.StartPositionY.Value = 0;
            app.EndPositionX.Value = 0;
            app.EndPositionY.Value = 0;

            app.Obs1X.Value = 0;
            app.Obs1Y.Value = 0;
            app.Obs1Radius.Value = 1;

            app.Obs2X.Value = 0;
            app.Obs2Y.Value = 0;
            app.Obs2Radius.Value = 1;

            app.Obs3X.Value = 0;
            app.Obs3Y.Value = 0;
            app.Obs3Radius.Value = 1;

            app.xlim.Value = 10;
            app.ylim.Value = 10;

            % Interne Variablen zurücksetzen
            app.safety = [];
    
            % Optional: Variablen im Workspace löschen
            evalin('base', 'clear start_pos end_pos X_value_obs Y_value_obs r_obs map result_path roverParams obstacles');

            % Hinweis
            msgbox('Initialisierung zurückgesetzt', 'Reset');
        end

        % Button pushed function: Simulinkstart
        function SimulinkstartButtonPushed(app, event)
            % Simulink öffnen (falls nicht geöffnet)
            if ~bdIsLoaded('Rover_Simulation_R2023b')
            load_system('Rover_Simulation_R2023b');  % meinModell.slx muss im Pfad liegen
            end

            % Modell anzeigen
            open_system('Rover_Simulation_R2023b');
    
            % Optional: sofort starten
            % set_param('meinModell', 'SimulationCommand', 'start');

            % Hinweis
            disp('Simulink-Modell gestartet.');

        end

        % Button pushed function: StartenSim
        function StartenSimButtonPushed(app, event)
            % Starten der Simulation
            set_param('Rover_Simulation_R2023b', 'SimulationCommand', 'start');
        end

        % Button pushed function: StoppenSim
        function StoppenSimButtonPushed(app, event)
            % Stoppen der Simulation
            set_param('Rover_Simulation_R2023b', 'SimulationCommand', 'stop');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Banner = uilabel(app.UIFigure);
            app.Banner.Text = 'Initialisierung Simulation';
            app.EingabeStartundZielpunkt = uilabel(app.UIFigure);
            app.EingabeStartundZielpunkt.Text = 'Eingabe Start- und Zielpunkt:';
            app.EingabederHindernisse = uilabel(app.UIFigure);
            app.EingabederHindernisse.Text = 'Eingabe der Hindernisse:';
            app.StartPositionXEditField_2Label = uilabel(app.UIFigure);
            app.StartPositionXEditField_2Label.Text = 'Start Position X:';
            app.StartPositionX = uieditfield(app.UIFigure, 'numeric');
            app.StartPositionX.Limits = [0 Inf];
            app.EndPositionXEditField_2Label = uilabel(app.UIFigure);
            app.EndPositionXEditField_2Label.Text = 'End Position X:';
            app.EndPositionX = uieditfield(app.UIFigure, 'numeric');
            app.EndPositionX.Limits = [0 Inf];
            app.StartPositionYEditField_2Label = uilabel(app.UIFigure);
            app.StartPositionYEditField_2Label.Text = 'Start Position Y:';
            app.StartPositionY = uieditfield(app.UIFigure, 'numeric');
            app.StartPositionY.Limits = [0 Inf];
            app.EndPositionYEditField_2Label = uilabel(app.UIFigure);
            app.EndPositionYEditField_2Label.Text = 'End Position Y:';
            app.EndPositionY = uieditfield(app.UIFigure, 'numeric');
            app.EndPositionY.Limits = [0 Inf];
            app.Obs1XEditField_2Label = uilabel(app.UIFigure);
            app.Obs1XEditField_2Label.Text = 'Obs 1 X:';
            app.Obs1X = uieditfield(app.UIFigure, 'numeric');
            app.Obs2XEditField_2Label = uilabel(app.UIFigure);
            app.Obs2XEditField_2Label.Text = 'Obs 2 X:';
            app.Obs2X = uieditfield(app.UIFigure, 'numeric');
            app.Obs3XEditField_2Label = uilabel(app.UIFigure);
            app.Obs3XEditField_2Label.Text = 'Obs 3 X:';
            app.Obs3X = uieditfield(app.UIFigure, 'numeric');
            app.Obs1YEditFieldLabel = uilabel(app.UIFigure);
            app.Obs1YEditFieldLabel.Text = 'Obs 1 Y:';
            app.Obs1Y = uieditfield(app.UIFigure, 'numeric');
            app.Obs2YEditFieldLabel = uilabel(app.UIFigure);
            app.Obs2YEditFieldLabel.Text = 'Obs 2 Y:';
            app.Obs2Y = uieditfield(app.UIFigure, 'numeric');
            app.Obs3YEditFieldLabel = uilabel(app.UIFigure);
            app.Obs3YEditFieldLabel.Text = 'Obs 3 Y:';
            app.Obs3Y = uieditfield(app.UIFigure, 'numeric');
            app.Obs1RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.Obs1RadiusEditFieldLabel.Text = 'Obs 1 Radius:';
            app.Obs1Radius = uieditfield(app.UIFigure, 'numeric');
            app.Obs2RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.Obs2RadiusEditFieldLabel.Text = 'Obs 2 Radius:';
            app.Obs2Radius = uieditfield(app.UIFigure, 'numeric');
            app.Obs3RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.Obs3RadiusEditFieldLabel.Text = 'Obs 3 Radius:';
            app.Obs3Radius = uieditfield(app.UIFigure, 'numeric');
            app.StartPfadplanungButton = uibutton(app.UIFigure, 'push');
            app.StartPfadplanungButton.ButtonPushedFcn = createCallbackFcn(app, @StartPfadplanungButtonPushed, true);
            app.StartPfadplanungButton.Text = 'Start Pfadplanung';
            app.StartenderPfadplanung = uilabel(app.UIFigure);
            app.StartenderPfadplanung.Text = 'Starten der Pfadplanung:';
            app.AchsgrenzenLabel = uilabel(app.UIFigure);
            app.AchsgrenzenLabel.Text = 'Achsgrenzen:';
            app.Obs2XEditField_2Label_2 = uilabel(app.UIFigure);
            app.Obs2XEditField_2Label_2.Text = 'X-Ende:';
            app.xlim = uieditfield(app.UIFigure, 'numeric');
            app.Obs3XEditField_2Label_2 = uilabel(app.UIFigure);
            app.Obs3XEditField_2Label_2.Text = 'Y-Ende:';
            app.ylim = uieditfield(app.UIFigure, 'numeric');
            app.PfadStatusLampLabel = uilabel(app.UIFigure);
            app.PfadStatusLampLabel.Text = 'Pfad Status:';
            app.PfadStatusLamp = uilamp(app.UIFigure);
            app.ResetInit = uibutton(app.UIFigure, 'push');
            app.ResetInit.ButtonPushedFcn = createCallbackFcn(app, @ResetInitButtonPushed, true);
            app.ResetInit.Text = 'Initialisierung zurücksetzen';
            app.ResetLabel = uilabel(app.UIFigure);
            app.ResetLabel.Text = 'Reset:';
            app.Simulinkstart = uibutton(app.UIFigure, 'push');
            app.Simulinkstart.ButtonPushedFcn = createCallbackFcn(app, @SimulinkstartButtonPushed, true);
            app.Simulinkstart.Text = 'Simulink starten';
            app.SimulinkLabel = uilabel(app.UIFigure);
            app.SimulinkLabel.Text = 'Simulink:';
            app.SimulinkSim = uilabel(app.UIFigure);
            app.SimulinkSim.Text = 'Simulink Simulation: ';
            app.StartenSim = uibutton(app.UIFigure, 'push');
            app.StartenSim.ButtonPushedFcn = createCallbackFcn(app, @StartenSimButtonPushed, true);
            app.StartenSim.Text = 'Starten';
            app.StoppenSim = uibutton(app.UIFigure, 'push');
            app.StoppenSim.ButtonPushedFcn = createCallbackFcn(app, @StoppenSimButtonPushed, true);
            app.StoppenSim.Text = 'Stoppen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Benutzereingabe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end