classdef Initialisierung < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        cancel                          matlab.ui.control.Button
        setSimulationTime               matlab.ui.control.Button
        SimulationszeitLabel            matlab.ui.control.Label
        simulationTime                  matlab.ui.control.NumericEditField
        wheelwidth                      matlab.ui.control.NumericEditField
        EditField_4Label_2              matlab.ui.control.Label
        wheelradius                     matlab.ui.control.NumericEditField
        RadRadiusLabel                  matlab.ui.control.Label
        hightBody                       matlab.ui.control.NumericEditField
        RoverHheLabel                   matlab.ui.control.Label
        widthBody                       matlab.ui.control.NumericEditField
        RoverBreiteLabel                matlab.ui.control.Label
        lengthBody                      matlab.ui.control.NumericEditField
        RoverLngeLabel                  matlab.ui.control.Label
        RoverparameterLabel             matlab.ui.control.Label
        StoppenSim                      matlab.ui.control.Button
        StartenSim                      matlab.ui.control.Button
        SimulinkSim                     matlab.ui.control.Label
        SimulinkLabel                   matlab.ui.control.Label
        Simulinkstart                   matlab.ui.control.Button
        ResetLabel                      matlab.ui.control.Label
        ResetInit                       matlab.ui.control.Button
        PfadStatusLamp                  matlab.ui.control.Lamp
        PfadStatusLampLabel             matlab.ui.control.Label
        ylim                            matlab.ui.control.NumericEditField
        Obs3XEditField_2Label_2         matlab.ui.control.Label
        xlim                            matlab.ui.control.NumericEditField
        Obs2XEditField_2Label_2         matlab.ui.control.Label
        AchsgrenzenLabel                matlab.ui.control.Label
        StartenderPfadplanung           matlab.ui.control.Label
        StartPfadplanungButton          matlab.ui.control.Button
        Obs3Radius                      matlab.ui.control.NumericEditField
        Obs3RadiusEditFieldLabel        matlab.ui.control.Label
        Obs2Radius                      matlab.ui.control.NumericEditField
        Obs2RadiusEditFieldLabel        matlab.ui.control.Label
        Obs1Radius                      matlab.ui.control.NumericEditField
        Obs1RadiusEditFieldLabel        matlab.ui.control.Label
        Obs3Y                           matlab.ui.control.NumericEditField
        Obs3YEditFieldLabel             matlab.ui.control.Label
        Obs2Y                           matlab.ui.control.NumericEditField
        Obs2YEditFieldLabel             matlab.ui.control.Label
        Obs1Y                           matlab.ui.control.NumericEditField
        Obs1YEditFieldLabel             matlab.ui.control.Label
        Obs3X                           matlab.ui.control.NumericEditField
        Obs3XEditField_2Label           matlab.ui.control.Label
        Obs2X                           matlab.ui.control.NumericEditField
        Obs2XEditField_2Label           matlab.ui.control.Label
        Obs1X                           matlab.ui.control.NumericEditField
        Obs1XEditField_2Label           matlab.ui.control.Label
        EndPositionY                    matlab.ui.control.NumericEditField
        EndPositionYEditField_2Label    matlab.ui.control.Label
        StartPositionY                  matlab.ui.control.NumericEditField
        StartPositionYEditField_2Label  matlab.ui.control.Label
        EndPositionX                    matlab.ui.control.NumericEditField
        EndPositionXEditField_2Label    matlab.ui.control.Label
        StartPositionX                  matlab.ui.control.NumericEditField
        StartPositionXEditField_2Label  matlab.ui.control.Label
        EingabederHindernisse           matlab.ui.control.Label
        EingabeStartundZielpunkt        matlab.ui.control.Label
        Banner                          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)

        % Roverparamter
        l; % [m]       Länge Rover Körper
        b; % [m]       Breite Rover Körper
        h; % [m]       Höhe Rover Körper
        r; % [m]       Rad Radius
        w; % [m]       Breite der Räder
        z; % Abstand zum Boden (für Visualisierung)

        % Achsgrenzen-Plot und Rastermap
        xlim_end;
        ylim_end;

        % Initialisierung Anfangszustände
        dthetas = ones(4,1);
        phi = ones(4,1);
        q0 = [];
        
        % Map parameter
        cellSize = 1.0; % Standardwert für Rastermap
        safety;

        % Simulationszeit
        simTime;

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        % Positionieren der GUI
        movegui(app.UIFigure, 'west');
            
        % Default-Werte des Rovers
        app.l = 2;    % [m]       Länge Rover Körper
        app.b = 1;    % [m]       Breite Rover Körper
        app.h = 0.5;  % [m]       Höhe Rover Körper
        app.r = 0.2;  % [m]       Rad Radius
        app.w = 0.2;  % [m]       Breite der Räder
        app.z = 1;      % Abstand zum Boden (für Visualisierung)

        % Default-Wert für q0
        app.q0 = zeros(3,1);
        
        % Default-Wert Sicherheitsabstand
        app.safety = (app.b / 2) + 1;

        % Default-Wert Simulationszeit
        app.simTime = 60;

        % GUI-Achslimit
        app.xlim_end = 25;
        app.ylim_end = 25;


        % GUI-Felder mit Default-Werten füllen
        app.lengthBody.Value = app.l;
        app.widthBody.Value = app.b;
        app.hightBody.Value = app.h;
        app.wheelradius.Value = app.r;
        app.wheelwidth.Value = app.w;
        
        % Plot
        app.xlim.Value = app.xlim_end;
        app.ylim.Value = app.ylim_end;

        % simTime
        app.simulationTime.Value = app.simTime;

        % Lampe grau setzen
        app.PfadStatusLamp.Color = [0.5 0.5 0.5];  % Grau
        app.PfadStatusLamp.Tooltip = 'Bereit';

        % Info init-button
        app.ResetInit.Tooltip = 'Eingabe Parameter zurücksetzen'

        % Info Pfadplannung
        app.StartPfadplanungButton.Tooltip = 'Starten der Pfadplannung'

        % Info Simulinkopen
        app.Simulinkstart.Tooltip = 'Öffnen des Rover-Modells'

        % Info Simulink start/stop
        app.StartenSim.Tooltip = 'Starten der Simulation'
        app.StoppenSim.Tooltip = 'Stoppen der Simulation'
        app.cancel.Tooltip = 'Simulation wird abgebrochen'

        % Info zur Simulationszeit
        app.simulationTime.Tooltip = 'Eingabe beliebige Simulationszeit'
        app.setSimulationTime.Tooltip = 'Zeit in Simulink setzen'

        % Anfangsparameter in Workspace schreiben
        % Rover-Parameter
        assignin('base', 'l', app.l);
        assignin('base', 'b', app.b);
        assignin('base', 'h', app.h);
        assignin('base', 'r', app.r);
        assignin('base', 'w', app.w);
        assignin('base', 'q0', app.q0);

        % Plot-Parameter
        assignin('base', 'xlim_end', app.xlim_end);
        assignin('base', 'ylim_end', app.ylim_end);

        % Hardcodierte Parameter
        assignin('base', 'z', app.z);
        assignin('base', 'dthetas', app.dthetas);
        assignin('base', 'phi', app.phi);
        assignin('base', 'cellSize', app.cellSize);
        assignin('base', 'safety', app.safety);
        end

        % Button pushed function: StartPfadplanungButton
        function StartPfadplanungButtonPushed(app, event)

            % Rover Parameter
            app.l = app.lengthBody.Value;
            app.b = app.widthBody.Value;
            app.h = app.hightBody.Value;
            app.r = app.wheelradius.Value;
            app.w = app.wheelwidth.Value;

            % Sart-,Ziel-,Obs.
            start_pos = [app.StartPositionX.Value, app.StartPositionY.Value, 0];
            end_pos   = [app.EndPositionX.Value, app.EndPositionY.Value, 0];
            
            X_value_obs = [app.Obs1X.Value, app.Obs2X.Value, app.Obs3X.Value];
            Y_value_obs = [app.Obs1Y.Value, app.Obs2Y.Value, app.Obs3Y.Value];
            r_obs       = [app.Obs1Radius.Value, app.Obs2Radius.Value, app.Obs3Radius.Value];
            
            % q0 für Integrator auf Startposition anpassen
            app.q0 = start_pos';

            % Rastermap erzeugen
            app.xlim_end = app.xlim.Value;
            app.ylim_end = app.ylim.Value;

            mapSize = [app.ylim_end, app.xlim_end] / app.cellSize;
            map = zeros(mapSize);
            
            % Sicherheitsabstand definieren
            app.safety = (app.b / 2) +1;

            % Eingabe der Simulationszeit
            app.simTime = app.simulationTime.Value;

            % Hindernis eintragen
            map = fct_markCircularObstacle(map, X_value_obs(1), Y_value_obs(1), r_obs(1) + app.safety, app.cellSize);
            map = fct_markCircularObstacle(map, X_value_obs(2), Y_value_obs(2), r_obs(2) + app.safety, app.cellSize);
            map = fct_markCircularObstacle(map, X_value_obs(3), Y_value_obs(3), r_obs(3) + app.safety, app.cellSize);
            
            % Start- und Endzelle
            start_cell = round(start_pos(1:2) / app.cellSize);
            goal_cell  = round(end_pos(1:2) / app.cellSize);
            
            % Überprüfung ob Start/Ziel in Hindernissen
            d_start = sqrt((start_pos(1) - X_value_obs).^2 + (start_pos(2) - Y_value_obs).^2);

            d_end = sqrt((end_pos(1) - X_value_obs).^2 + (end_pos(2) - Y_value_obs).^2);

            if any(d_start <= (r_obs + app.safety)) || any(d_end <= (r_obs + app.safety))
                    errordlg('Start oder Ziel liegen im Hindernis!', 'Hinderniswarnung');
                    app.PfadStatusLamp.Color = 'red';  % Rot
                    app.PfadStatusLamp.Tooltip = 'Ungültige Start-/Zielposition';
                return;
            end
            
            % BFS starten
            result_path = bfs_pathfinding(map, start_cell, goal_cell);
            
            if isempty(result_path)
                    errordlg('Kein Pfad gefunden!', 'Fehler');
                    app.PfadStatusLamp.Color = 'red';
                    app.PfadStatusLamp.Tooltip = 'Kein Pfad gefunden';
            else
                    % msgbox('Pfad erfolgreich erstellt!', 'Erfolg');
                    app.PfadStatusLamp.Color = 'green';
                    app.PfadStatusLamp.Tooltip = 'Pfad erfolgreich gefunden';
                
            % Visualisierung der Ansicht in AppDesigner
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, [0 app.xlim_end 0 app.ylim_end]);
            axis(app.UIAxes, 'equal');
            app.UIAxes.Color = 'w'; % Hintergrund weiß
            grid(app.UIAxes, 'on');

            % Hindernisse visualisieren
            theta = linspace(0, 2*pi, 100); 
            for i = 1:length(X_value_obs)
                x = X_value_obs(i);
                y = Y_value_obs(i);
                r_obs_arr = r_obs(i);
    
            % Kreise zeichnen
            fill(app.UIAxes, x + r_obs_arr*cos(theta), y + r_obs_arr*sin(theta), [0.8 0.3 0.3], ...
                'FaceAlpha', 0.3, 'EdgeColor', [0.8 0.3 0.3], 'LineWidth', 1.5);
            end

            % Pfad zeichnen
            plot(app.UIAxes, result_path(:,1) * app.cellSize, result_path(:,2) * app.cellSize, ...
                'b.-', 'LineWidth', 2);

            % Startpunkt (grün)
            plot(app.UIAxes, start_pos(1), start_pos(2), 'go', ...
                'MarkerSize', 10, 'MarkerFaceColor', 'g');

            % Endpunkt (rot)
            plot(app.UIAxes, end_pos(1), end_pos(2), 'ro', ...
                'MarkerSize', 10, 'MarkerFaceColor', 'r');

            hold(app.UIAxes, 'off');


                % Parameter an die MATLAB-Workspace senden
                
                % Arbeitsparameter
                assignin('base', 'start_pos', start_pos);
                assignin('base', 'end_pos', end_pos);
                assignin('base', 'xlim_end', app.xlim_end);
                assignin('base', 'ylim_end', app.ylim_end);
                assignin('base', 'X_value_obs', X_value_obs);
                assignin('base', 'Y_value_obs', Y_value_obs);
                assignin('base', 'r_obs', r_obs);
                assignin('base', 'map', map);
                assignin('base', 'result_path', result_path);

                % Rover-Parameter
                assignin('base', 'l', app.l);
                assignin('base', 'b', app.b);
                assignin('base', 'h', app.h);
                assignin('base', 'r', app.r);
                assignin('base', 'w', app.w);

                % Hardcodierte Parameter
                assignin('base', 'z', app.z);
                assignin('base', 'dthetas', app.dthetas);
                assignin('base', 'phi', app.phi);
                assignin('base', 'q0', app.q0);
                assignin('base', 'cellSize', app.cellSize);
                assignin('base', 'safety', app.safety);
                
            end
        end

        % Button pushed function: ResetInit
        function ResetInitButtonPushed(app, event)
            % Achsen leeren und zurücksetzen
            cla(app.UIAxes);
            app.UIAxes.Color = 'w';  % Hintergrund weiß
            axis(app.UIAxes, 'auto');
            grid(app.UIAxes, 'off');

            % Lampe zurücksetzen
            app.PfadStatusLamp.Color = [0.5 0.5 0.5];  % Grau
            app.PfadStatusLamp.Tooltip = 'Bereit';

            % Eingabefelder zurücksetzen

            % Rover parameter - Default
            app.l = 2;
            app.b = 1;
            app.h = 0.5;
            app.r = 5;
            app.w = 0.2;

            % SimTime - Default
            app.simTime = 60;

            % Parameter in GUI schreiben
            app.lengthBody.Value = 2;
            app.widthBody.Value = 1;
            app.hightBody.Value = 0.5;
            app.wheelradius.Value = 0.2;
            app.wheelwidth.Value = 0.2;
            
            app.StartPositionX.Value = 0;
            app.StartPositionY.Value = 0;
            app.EndPositionX.Value = 0;
            app.EndPositionY.Value = 0;

            app.Obs1X.Value = 0;
            app.Obs1Y.Value = 0;
            app.Obs1Radius.Value = 0;

            app.Obs2X.Value = 0;
            app.Obs2Y.Value = 0;
            app.Obs2Radius.Value = 0;

            app.Obs3X.Value = 0;
            app.Obs3Y.Value = 0;
            app.Obs3Radius.Value = 0;

            app.xlim.Value = 25;
            app.ylim.Value = 25;

            % Default-Wert für q0
            % app.q0 = zeros(3,1);

            % Interne Variablen zurücksetzen
            app.safety = 2;
    
            % Optional: Variablen im Workspace löschen
            evalin('base', 'clear start_pos end_pos X_value_obs Y_value_obs r_obs map result_path roverParams obstacles q0');

            % Hinweis
            msgbox('Initialisierung zurückgesetzt', 'Reset');
        end

        % Button pushed function: Simulinkstart
        function SimulinkstartButtonPushed(app, event)
            % Simulink öffnen (falls nicht geöffnet)
            if ~bdIsLoaded('Rover_Simulation_R2023b')
            load_system('Rover_Simulation_R2023b');  % meinModell.slx muss im Pfad liegen
            end

            % Modell anzeigen
            open_system('Rover_Simulation_R2023b');

            % Hinweis
            disp('Simulink-Modell gestartet.');

        end

        % Button pushed function: StartenSim
        function StartenSimButtonPushed(app, event)
       % Starten der Simulation
       
       % Simulink offen?
        if isempty(find_system('type', 'block_diagram'))
            warndlg('Simulink ist nicht geöffnet. Bitte öffnen Sie Simulink, bevor Sie die Simulation starten.', ...
                    'Simulink nicht geöffnet');
            return;
        end

        % Starten der Simulation
        try
            set_param('Rover_Simulation_R2023b', 'SimulationCommand', 'start');
            disp('Simulation gestartet.');
        catch ME
            % Fehlermeldung, falls Modell nicht gefunden oder anderer Fehler
            warndlg(['Simulation konnte nicht gestartet werden: ' ME.message], 'Fehler beim Start');
        end
        end

        % Button pushed function: StoppenSim
        function StoppenSimButtonPushed(app, event)

         if isempty(find_system('type', 'block_diagram'))
            warndlg('Simulink ist nicht geöffnet. Bitte starten Sie die Simulation, bevor Sie sie stoppen möchten.', ...
                    'Simulink nicht geöffnet');
            return;
        end

        % Stoppen der Simulation
        try
            set_param('Rover_Simulation_R2023b', 'SimulationCommand', 'stop');
            disp('Simulation gestoppt.');
        catch ME
            % Fehlermeldung, falls Modell nicht gefunden oder anderer Fehler
            warndlg(['Simulation konnte nicht gestoppt werden: ' ME.message], 'Fehler beim Stoppen');
        end

        end

        % Button pushed function: setSimulationTime
        function setSimulationTimeButtonPushed(app, event)
        % Wert aus GUI holen
            app.simTime = app.simulationTime.Value;

        
            if app.simTime <= 0
                warndlg('Die Simulationszeit muss größer als 0 sein.', 'Ungültiger Wert');
                return;
            end

        % Check ob Simulink geöffnet
            if isempty(find_system('type', 'block_diagram'))
                warndlg('Simulink ist nicht geöffnet. Bitte öffnen Sie Simulink, bevor Sie die Simulationzeit setzen.', ...
                    'Simulink nicht geöffnet');
                return;
            end

        % Setze die Simulationszeit im Modell
        try
            set_param('Rover_Simulation_R2023b', 'StopTime', num2str(app.simTime));
            disp(['Simulationszeit auf ', num2str(app.simTime), ' s gesetzt.']);
        catch ME
            uialert(app.UIFigure, ['Fehler beim Setzen der Simulationszeit: ', ME.message], 'Fehler');
        end
        end

        % Button pushed function: cancel
        function cancelButtonPushed(app, event)
            % Simulink offen?
            if isempty(find_system('type', 'block_diagram'))
                warndlg('Simulink ist nicht geöffnet. Bitte starten Sie die Simulation, bevor Sie sie stoppen möchten.', ...
                    'Simulink nicht geöffnet');
                return;
            end

            % Modell geladen?
            if ~bdIsLoaded('Rover_Simulation_R2023b')
                warndlg('Das Modell "Rover_Simulation_R2023b" ist nicht geladen.', ...
                    'Modell nicht gefunden');
                return;
            end

            % Läuft die Simulation?
            simStatus = get_param('Rover_Simulation_R2023b', 'SimulationStatus');
            if ~strcmp(simStatus, 'running')
                warndlg('Die Simulation läuft derzeit nicht.', 'Kein Stopp notwendig');
                return;
            end

            % Simulation stoppen
            try
                set_param('Rover_Simulation_R2023b', 'SimulationCommand', 'stop');
                disp('Simulation wurde sauber gestoppt.');

                 % figures schließen
                % close all force;
                close all;
            catch ME
                warndlg(['Simulation konnte nicht gestoppt werden: ' ME.message], 'Fehler beim Stoppen');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Banner = uilabel(app.UIFigure);
            app.Banner.Text = 'Initialisierung Simulation';
            app.EingabeStartundZielpunkt = uilabel(app.UIFigure);
            app.EingabeStartundZielpunkt.Text = 'Eingabe Start- und Zielpunkt:';
            app.EingabederHindernisse = uilabel(app.UIFigure);
            app.EingabederHindernisse.Text = 'Eingabe der Hindernisse:';
            app.StartPositionXEditField_2Label = uilabel(app.UIFigure);
            app.StartPositionXEditField_2Label.Text = 'Start Position X:';
            app.StartPositionX = uieditfield(app.UIFigure, 'numeric');
            app.StartPositionX.Limits = [0 Inf];
            app.EndPositionXEditField_2Label = uilabel(app.UIFigure);
            app.EndPositionXEditField_2Label.Text = 'End Position X:';
            app.EndPositionX = uieditfield(app.UIFigure, 'numeric');
            app.EndPositionX.Limits = [0 Inf];
            app.StartPositionYEditField_2Label = uilabel(app.UIFigure);
            app.StartPositionYEditField_2Label.Text = 'Start Position Y:';
            app.StartPositionY = uieditfield(app.UIFigure, 'numeric');
            app.StartPositionY.Limits = [0 Inf];
            app.EndPositionYEditField_2Label = uilabel(app.UIFigure);
            app.EndPositionYEditField_2Label.Text = 'End Position Y:';
            app.EndPositionY = uieditfield(app.UIFigure, 'numeric');
            app.EndPositionY.Limits = [0 Inf];
            app.Obs1XEditField_2Label = uilabel(app.UIFigure);
            app.Obs1XEditField_2Label.Text = 'Obs 1 X:';
            app.Obs1X = uieditfield(app.UIFigure, 'numeric');
            app.Obs1X.Limits = [0 Inf];
            app.Obs2XEditField_2Label = uilabel(app.UIFigure);
            app.Obs2XEditField_2Label.Text = 'Obs 2 X:';
            app.Obs2X = uieditfield(app.UIFigure, 'numeric');
            app.Obs2X.Limits = [0 Inf];
            app.Obs3XEditField_2Label = uilabel(app.UIFigure);
            app.Obs3XEditField_2Label.Text = 'Obs 3 X:';
            app.Obs3X = uieditfield(app.UIFigure, 'numeric');
            app.Obs3X.Limits = [0 Inf];
            app.Obs1YEditFieldLabel = uilabel(app.UIFigure);
            app.Obs1YEditFieldLabel.Text = 'Obs 1 Y:';
            app.Obs1Y = uieditfield(app.UIFigure, 'numeric');
            app.Obs1Y.Limits = [0 Inf];
            app.Obs2YEditFieldLabel = uilabel(app.UIFigure);
            app.Obs2YEditFieldLabel.Text = 'Obs 2 Y:';
            app.Obs2Y = uieditfield(app.UIFigure, 'numeric');
            app.Obs2Y.Limits = [0 Inf];
            app.Obs3YEditFieldLabel = uilabel(app.UIFigure);
            app.Obs3YEditFieldLabel.Text = 'Obs 3 Y:';
            app.Obs3Y = uieditfield(app.UIFigure, 'numeric');
            app.Obs3Y.Limits = [0 Inf];
            app.Obs1RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.Obs1RadiusEditFieldLabel.Text = 'Obs 1 Radius:';
            app.Obs1Radius = uieditfield(app.UIFigure, 'numeric');
            app.Obs1Radius.Limits = [0 Inf];
            app.Obs2RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.Obs2RadiusEditFieldLabel.Text = 'Obs 2 Radius:';
            app.Obs2Radius = uieditfield(app.UIFigure, 'numeric');
            app.Obs2Radius.Limits = [0 Inf];
            app.Obs3RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.Obs3RadiusEditFieldLabel.Text = 'Obs 3 Radius:';
            app.Obs3Radius = uieditfield(app.UIFigure, 'numeric');
            app.Obs3Radius.Limits = [0 Inf];
            app.StartPfadplanungButton = uibutton(app.UIFigure, 'push');
            app.StartPfadplanungButton.ButtonPushedFcn = createCallbackFcn(app, @StartPfadplanungButtonPushed, true);
            app.StartPfadplanungButton.Text = 'Start Pfadplanung';
            app.StartenderPfadplanung = uilabel(app.UIFigure);
            app.StartenderPfadplanung.Text = 'Starten der Pfadplanung:';
            app.AchsgrenzenLabel = uilabel(app.UIFigure);
            app.AchsgrenzenLabel.Text = 'Achsgrenzen:';
            app.Obs2XEditField_2Label_2 = uilabel(app.UIFigure);
            app.Obs2XEditField_2Label_2.Text = 'X-Ende:';
            app.xlim = uieditfield(app.UIFigure, 'numeric');
            app.xlim.Limits = [0 Inf];
            app.Obs3XEditField_2Label_2 = uilabel(app.UIFigure);
            app.Obs3XEditField_2Label_2.Text = 'Y-Ende:';
            app.ylim = uieditfield(app.UIFigure, 'numeric');
            app.ylim.Limits = [0 Inf];
            app.PfadStatusLampLabel = uilabel(app.UIFigure);
            app.PfadStatusLampLabel.Text = 'Pfad Status:';
            app.PfadStatusLamp = uilamp(app.UIFigure);
            app.ResetInit = uibutton(app.UIFigure, 'push');
            app.ResetInit.ButtonPushedFcn = createCallbackFcn(app, @ResetInitButtonPushed, true);
            app.ResetInit.Text = 'Initialisierung zurücksetzen';
            app.ResetLabel = uilabel(app.UIFigure);
            app.ResetLabel.Text = 'Reset:';
            app.Simulinkstart = uibutton(app.UIFigure, 'push');
            app.Simulinkstart.ButtonPushedFcn = createCallbackFcn(app, @SimulinkstartButtonPushed, true);
            app.Simulinkstart.Text = 'Simulink starten';
            app.SimulinkLabel = uilabel(app.UIFigure);
            app.SimulinkLabel.Text = 'Simulink:';
            app.SimulinkSim = uilabel(app.UIFigure);
            app.SimulinkSim.Text = 'Simulink Simulation: ';
            app.StartenSim = uibutton(app.UIFigure, 'push');
            app.StartenSim.ButtonPushedFcn = createCallbackFcn(app, @StartenSimButtonPushed, true);
            app.StartenSim.Text = 'Starten';
            app.StoppenSim = uibutton(app.UIFigure, 'push');
            app.StoppenSim.ButtonPushedFcn = createCallbackFcn(app, @StoppenSimButtonPushed, true);
            app.StoppenSim.Text = 'Stoppen';
            app.RoverparameterLabel = uilabel(app.UIFigure);
            app.RoverparameterLabel.Text = 'Roverparameter:';
            app.RoverLngeLabel = uilabel(app.UIFigure);
            app.RoverLngeLabel.Text = 'Rover-Länge:';
            app.lengthBody = uieditfield(app.UIFigure, 'numeric');
            app.lengthBody.Limits = [0 Inf];
            app.RoverBreiteLabel = uilabel(app.UIFigure);
            app.RoverBreiteLabel.Text = 'Rover-Breite:';
            app.widthBody = uieditfield(app.UIFigure, 'numeric');
            app.widthBody.Limits = [0 Inf];
            app.RoverHheLabel = uilabel(app.UIFigure);
            app.RoverHheLabel.Text = 'Rover-Höhe:';
            app.hightBody = uieditfield(app.UIFigure, 'numeric');
            app.hightBody.Limits = [0 Inf];
            app.RadRadiusLabel = uilabel(app.UIFigure);
            app.RadRadiusLabel.Text = 'Rad-Radius:';
            app.wheelradius = uieditfield(app.UIFigure, 'numeric');
            app.wheelradius.Limits = [0 Inf];
            app.EditField_4Label_2 = uilabel(app.UIFigure);
            app.EditField_4Label_2.Text = 'Rad-Breite:';
            app.wheelwidth = uieditfield(app.UIFigure, 'numeric');
            app.wheelwidth.Limits = [0 Inf];
            app.simulationTime = uieditfield(app.UIFigure, 'numeric');
            app.simulationTime.Limits = [0 Inf];
            app.SimulationszeitLabel = uilabel(app.UIFigure);
            app.SimulationszeitLabel.Text = 'Simulationszeit:';
            app.setSimulationTime = uibutton(app.UIFigure, 'push');
            app.setSimulationTime.ButtonPushedFcn = createCallbackFcn(app, @setSimulationTimeButtonPushed, true);
            app.setSimulationTime.Text = 'Set time';
            app.cancel = uibutton(app.UIFigure, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancel.Text = 'Abbrechen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Initialisierung

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end